classdef SERIES_AND_PARALLEL_RLC_ANALYZER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RESETButton                   matlab.ui.control.Button
        HintButton                    matlab.ui.control.Button
        Image2_2                      matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        TimeEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        VoltageSourceEditField        matlab.ui.control.EditField
        VoltageSourceEditFieldLabel   matlab.ui.control.Label
        CurrentSourceEditField        matlab.ui.control.EditField
        CurrentSourceEditFieldLabel   matlab.ui.control.Label
        InitialCapacitorVoltageEditField  matlab.ui.control.EditField
        InitialCapacitorVoltageEditFieldLabel  matlab.ui.control.Label
        InitialInductorCurrentEditField  matlab.ui.control.EditField
        InitialInductorCurrentEditFieldLabel  matlab.ui.control.Label
        CapacitorCEditField           matlab.ui.control.EditField
        CapacitorCEditFieldLabel      matlab.ui.control.Label
        InductorLEditField            matlab.ui.control.EditField
        InductorLEditFieldLabel       matlab.ui.control.Label
        ResistorREditField            matlab.ui.control.EditField
        ResistorREditFieldLabel       matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        sLabel                        matlab.ui.control.Label
        VLabel_2                      matlab.ui.control.Label
        ALabel_2                      matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ResultsTab                    matlab.ui.container.Tab
        StepLabel                     matlab.ui.control.Label
        NaturalLabel                  matlab.ui.control.Label
        EditField_20                  matlab.ui.control.EditField
        EditField_19                  matlab.ui.control.EditField
        radsLabel_5                   matlab.ui.control.Label
        radsLabel_4                   matlab.ui.control.Label
        radsLabel_3                   matlab.ui.control.Label
        radsLabel_2                   matlab.ui.control.Label
        radsLabel                     matlab.ui.control.Label
        EditField_18                  matlab.ui.control.EditField
        EditField_17                  matlab.ui.control.EditField
        EditField_15                  matlab.ui.control.EditField
        EditField_14                  matlab.ui.control.EditField
        EditField_13                  matlab.ui.control.EditField
        EditField_12                  matlab.ui.control.EditField
        EditField_11                  matlab.ui.control.EditField
        CoefficientLabel_2            matlab.ui.control.Label
        CoefficientLabel              matlab.ui.control.Label
        ResponsetypeLabel             matlab.ui.control.Label
        EditField_7                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        S2Label                       matlab.ui.control.Label
        S1Label                       matlab.ui.control.Label
        DampedFrequencyLabel          matlab.ui.control.Label
        ResonantRadianFrequencyLabel  matlab.ui.control.Label
        NeperFrequencyLabel           matlab.ui.control.Label
        SeriesNtrlGraphTab            matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        SeriesStepGraphTab            matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        ParallelNaturalGraphTab       matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        ParallelStepGraphTab          matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        ResultsLabel                  matlab.ui.control.Label
        VLabel                        matlab.ui.control.Label
        ALabel                        matlab.ui.control.Label
        FLabel                        matlab.ui.control.Label
        HLabel                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        InitialConditionsLabel        matlab.ui.control.Label
        ParametersLabel               matlab.ui.control.Label
        CALCULATEButton               matlab.ui.control.Button
        RLCCircuitAnalysisToolLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            Value = app.Switch.Value;
            Switchvalue=0;
            if strcmp(Value,'Series')
                Switchvalue=0;

            elseif strcmp(Value,'Parallel')
                Switchvalue=2;
            end 
            
            if  Switchvalue==2        %Parallel
                R = str2double(app.ResistorREditField.Value);
                L = str2double(app.InductorLEditField.Value);
                C = str2double(app.CapacitorCEditField.Value);
                            
                InitialIC = str2double(app.InitialInductorCurrentEditField.Value);
                InitialCV = str2double(app.InitialCapacitorVoltageEditField.Value);
                InitialCS = str2double(app.CurrentSourceEditField.Value);
                Time = str2double(app.TimeEditField.Value);
                I0 = InitialIC;
                V0 = InitialCV;
                Is = InitialCS;

                Neper = 1/(2*R*C);
                app.EditField.Value = Neper ;
                ResonantRadian = 1/sqrt(L*C);
                app.EditField_2.Value = ResonantRadian ;
                S1 = -(Neper)+sqrt((Neper^2)-(ResonantRadian^2));
                SS1 = num2str(S1);
                app.EditField_11.Value = SS1;
                S2 = -(Neper)-sqrt((Neper^2)-(ResonantRadian^2));
                SS2 = num2str(S2);
                app.EditField_12.Value = SS2;
                DampedRadian = sqrt((ResonantRadian^2)-(Neper^2));
                DampedRadiannn = num2str(DampedRadian);
                app.EditField_15.Value = DampedRadiannn;
                
                if ResonantRadian < Neper 
                app.EditField_7.Value = 'Over-Damped' ;
                app.EditField_13.Value = 'A1';
                app.EditField_14.Value = 'A2';
                elseif ResonantRadian > Neper
                app.EditField_7.Value = 'Under-Damped' ;
                app.EditField_13.Value = 'B1';
                app.EditField_14.Value = 'B2';
                else
                app.EditField_7.Value = 'Critically-Damped' ;
                app.EditField_13.Value = 'D1';
                app.EditField_14.Value = 'D2';
                end
             
                %Natural Responses

                if ResonantRadian < Neper
                    eqns=[1 1;S1 S2];
                    res=[V0;-(V0+R*I0)/(R*C)];
                    sonuc=linsolve(eqns,res);
                    A1 = sonuc(1);
                    A2 = sonuc(2);
                    A1 = string(A1);
                    A2 = string(A2);
                    app.EditField_17.Value=A1;
                    app.EditField_18.Value=A2;

                    A1 = str2double(A1);
                    A2 = str2double(A2);
                elseif ResonantRadian > Neper
                    eqns = [1 0;(-Neper) DampedRadian];
                    res = [V0;-(V0+R*I0)/(R*C)];
                    sonuc=linsolve(eqns,res);
                    B1 = sonuc(1);
                    B2 = sonuc(2);
                    B1 = string(B1);
                    B2 = string(B2);
                    app.EditField_17.Value=B1;
                    app.EditField_18.Value=B2;

                    B1 = str2double(B1);
                    B2 = str2double(B2);
                elseif ResonantRadian == Neper
                    eqns = [0 1;1 (-Neper)];
                    res = [V0;-(V0+R*I0)/(R*C)];
                    sonuc=linsolve(eqns,res);
                    D1 = sonuc(1);
                    D2 = sonuc(2);
                    D1 = string(D1);
                    D2 = string(D2);
                    app.EditField_17.Value=D1;
                    app.EditField_18.Value=D2;

                    D1 = str2double(D1);
                    D2 = str2double(D2);
                end
                
                PNValues = [];
                for t = 0:0.0001:Time
                    if ResonantRadian < Neper 
                        pnv = A1*exp(S1*t)+ A2*exp(S2*t); % Over-Damped
                        PNValues = [PNValues;pnv];
                    elseif ResonantRadian > Neper
                        pnv = B1*exp(-Neper*t)*cos(DampedRadian*t) + B2*exp(-Neper*t)*sin(DampedRadian*t); %Under-Damped
                        PNValues = [PNValues;pnv];
                    elseif ResonantRadian == Neper
                        pnv = D1*t*exp(-Neper*t) + D2*exp(-Neper*t);  % Critically-Damped
                        PNValues = [PNValues;pnv];
                    end
                end
                t = (0:0.0001:Time);
                plot(app.UIAxes2,t,PNValues);

                %Step Responses
                if ResonantRadian < Neper
                    Seqns=[1 1;S1 S2];
                    Sres=[I0-Is;((-1/L)*(R*I0+V0))];
                    Ssonuc=linsolve(Seqns,Sres);
                    A1s = Ssonuc(1);
                    A2s = Ssonuc(2);
                    A1s = string(A1s);
                    A2s = string(A2s);
                    app.EditField_19.Value=A1s;
                    app.EditField_20.Value=A2s;

                    A1s = str2double(A1s);
                    A2s = str2double(A2s);
                elseif ResonantRadian > Neper
                    Seqns = [1 0;(-Neper) DampedRadian];
                    Sres = [I0-Is;((-1/L)*(R*I0+V0))];
                    Ssonuc=linsolve(Seqns,Sres);
                    B1s = Ssonuc(1);
                    B2s = Ssonuc(2);
                    B1s = string(B1s);
                    B2s = string(B2s);
                    app.EditField_19.Value=B1s;
                    app.EditField_20.Value=B2s;

                    B1s = str2double(B1s);
                    B2s = str2double(B2s);
                elseif ResonantRadian == Neper
                    Seqns = [0 1;1 (-Neper)];
                    Sres = [I0-Is;((-1/L)*(R*I0+V0))];
                    Ssonuc=linsolve(Seqns,Sres);
                    D1s = Ssonuc(1);
                    D2s = Ssonuc(2);
                    D1s = string(D1s);
                    D2s = string(D2s);
                    app.EditField_19.Value=D1s;
                    app.EditField_20.Value=D2s;

                    D1s = str2double(D1s);
                    D2s = str2double(D2s);
                end
                
                PSValues = [];
                
                %Step Responses
                for t = 0:0.0001:Time
                    if ResonantRadian < Neper
                        psi = A1s*exp(S1*t)+ A2s*exp(S2*t)+Is; % Over-Damped
                        PSValues = [PSValues;psi];
                    elseif ResonantRadian > Neper
                        psi = B1s*exp(-Neper*t)*cos(DampedRadian*t) + B2s*exp(-Neper*t)*sin(DampedRadian*t)+Is; %Under-Damped
                        PSValues = [PSValues;psi];
                    elseif ResonantRadian == Neper
                        psi = D1s*t*exp(-Neper*t) + D2s*exp(-Neper*t)+Is;  % Critically-Damped
                        PSValues = [PSValues;psi];
                    end
                end
                t = (0:0.0001:Time);
                plot(app.UIAxes,t,PSValues);


            elseif Switchvalue==0           %Series
                R = str2double(app.ResistorREditField.Value);
                L = str2double(app.InductorLEditField.Value);
                C = str2double(app.CapacitorCEditField.Value);
                
                InitialIC = str2double(app.InitialInductorCurrentEditField.Value);
                InitialCV = str2double(app.InitialCapacitorVoltageEditField.Value);
                InitialVS = str2double(app.VoltageSourceEditField.Value);
                Time = str2double(app.TimeEditField.Value);
                I0 = InitialIC;
                V0 = InitialCV;
                Vs = InitialVS;
                               
                Neper = R/(2*L);
                app.EditField.Value = Neper ;
                ResonantRadian = 1/sqrt(L*C);
                app.EditField_2.Value = ResonantRadian ;
                S1 = -(Neper)+sqrt((Neper^2)-(ResonantRadian^2));
                SS1 = num2str(S1);
                app.EditField_11.Value = SS1;
                S2 = -(Neper)-sqrt((Neper^2)-(ResonantRadian^2));
                SS2 = num2str(S2);
                app.EditField_12.Value = SS2;
                DampedRadian = sqrt((ResonantRadian^2)-(Neper^2));
                DampedRadiannn = num2str(DampedRadian);
                app.EditField_15.Value = DampedRadiannn;

                if ResonantRadian < Neper 
                app.EditField_7.Value = 'Over-Damped' ;
                app.EditField_13.Value = 'A1';
                app.EditField_14.Value = 'A2';
                elseif ResonantRadian > Neper
                app.EditField_7.Value = 'Under-Damped' ;
                app.EditField_13.Value = 'B1';
                app.EditField_14.Value = 'B2';
                else
                app.EditField_7.Value = 'Critically-Damped' ;
                app.EditField_13.Value = 'D1';
                app.EditField_14.Value = 'D2';
                end
                
                
              %Natural Responses  
                if ResonantRadian < Neper           
                    eqns=[1 1;S1 S2];
                    res=[I0;(-1/L)*(R*I0+V0)];
                    sonuc=linsolve(eqns,res);
                    A1 = sonuc(1);
                    A2 = sonuc(2);
                    A1 = string(A1);
                    A2 = string(A2);
                    app.EditField_17.Value=A1;
                    app.EditField_18.Value=A2;

                    A1 = str2double(A1);
                    A2 = str2double(A2);
                elseif ResonantRadian > Neper
                    eqns = [1 0;-Neper DampedRadian];
                    res = [I0;(-1/L)*(R*I0+V0)];
                    sonuc=linsolve(eqns,res);
                    B1 = sonuc(1);
                    B2 = sonuc(2);
                    B1 = I0;
                    B1 = string(B1);
                    B2 = string(B2);
                    app.EditField_17.Value=B1;
                    app.EditField_18.Value=B2;
                    
                    B1 = str2double(B1);
                    B2 = str2double(B2);
                elseif ResonantRadian == Neper
                    eqns = [0 1;1 -Neper];
                    res = [I0;(-1/L)*(R*I0+V0)];
                    sonuc=linsolve(eqns,res);
                    D1 = sonuc(1);
                    D2 = sonuc(2);
                    D1 = string(D1);
                    D2 = string(D2);
                    app.EditField_17.Value=D1;
                    app.EditField_18.Value=D2;

                    D1 = str2double(D1);
                    D2 = str2double(D2);
                end

                SNValues = [];
                for t = 0:0.0001:Time
                    if ResonantRadian < Neper 
                        sni = A1*(exp(S1*t))+ A2*(exp(S2*t)); % Over-Damped
                        SNValues = [SNValues;sni];
                    elseif ResonantRadian > Neper
                        sni = B1*(exp(-Neper*t))*cos(DampedRadian*t) + B2*(exp(-Neper*t))*sin(DampedRadian*t); %Under-Damped
                        SNValues = [SNValues;sni];
                    elseif ResonantRadian == Neper
                        sni = D1*t*exp(-Neper*t) + D2*exp(-Neper*t);  % Critically-Damped
                        SNValues = [SNValues;sni];
                    end
                end
                t = (0:0.0001:Time);
                plot(app.UIAxes3,t,SNValues);

                %Step Responses
                if ResonantRadian < Neper
                    Seqns=[1 1;S1 S2];
                    Sres=[V0-Vs;(-(V0+R*I0)/(R*C))];
                    Ssonuc=linsolve(Seqns,Sres);
                    A1s = Ssonuc(1);
                    A2s = Ssonuc(2);
                    A1s = string(A1s);
                    A2s = string(A2s);
                    app.EditField_19.Value=A1s;
                    app.EditField_20.Value=A2s;

                    A1s = str2double(A1s);
                    A2s = str2double(A2s);
                elseif ResonantRadian > Neper
                    Seqns = [1 0;(-Neper) DampedRadian];
                    Sres=[V0-Vs;(-(V0+R*I0)/(R*C))];
                    Ssonuc=linsolve(Seqns,Sres);
                    B1s = Ssonuc(1);
                    B2s = Ssonuc(2);
                    B1s = V0-Vs;
                    B1s = string(B1s);
                    B2s = string(B2s);
                    app.EditField_19.Value=B1s;
                    app.EditField_20.Value=B2s;

                    B1s = str2double(B1s);
                    B2s = str2double(B2s);
                elseif ResonantRadian == Neper
                    Seqns = [0 1;1 (-Neper)];
                    Sres=[V0-Vs;(-(V0+R*I0)/(R*C))];
                    Ssonuc=linsolve(Seqns,Sres);
                    D1s = Ssonuc(1);
                    D2s = Ssonuc(2);
                    D1s = string(D1s);
                    D2s = string(D2s);
                    app.EditField_19.Value=D1s;
                    app.EditField_20.Value=D2s;

                    D1s = str2double(D1s);
                    D2s = str2double(D2s);
                end
                
                SSValues = [];
                %Step Responses
                for t = 0:0.0001:Time
                    if ResonantRadian < Neper
                        ssv = A1s*exp(S1*t)+ A2s*exp(S2*t)+Vs; % Over-Damped
                        SSValues = [SSValues;ssv];
                    elseif ResonantRadian > Neper
                        ssv = B1s*(exp(-Neper*t))*cos(DampedRadian*t) + B2s*(exp(-Neper*t))*sin(DampedRadian*t)+Vs; %Under-Damped
                        SSValues = [SSValues;ssv];
                    elseif ResonantRadian == Neper
                        ssv = D1s*t*exp(-Neper*t) + D2s*exp(-Neper*t)+Vs;  % Critically-Damped
                        SSValues = [SSValues;ssv];
                    end
                end
                t = (0:0.0001:Time);
                plot(app.UIAxes4,t,SSValues);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            Value = app.Switch.Value;
            Switchvalue=0;
            app.CurrentSourceEditField.Visible = "off";
            app.CurrentSourceEditFieldLabel.Visible = "off";
            app.ALabel_2.Visible = 'off';
            app.VoltageSourceEditField.Visible = 'on';
            app.VoltageSourceEditFieldLabel.Visible = 'on';
            app.VLabel_2.Visible = 'on';
            if strcmp(Value,'Series')
                Switchvalue=0;
                app.VoltageSourceEditField.Visible = 'on';
                app.VoltageSourceEditFieldLabel.Visible = 'on';
                app.VLabel_2.Visible = 'on';
                app.CurrentSourceEditField.Visible = "off";
                app.CurrentSourceEditFieldLabel.Visible = "off";
                app.ALabel_2.Visible = 'off';
            elseif strcmp(Value,'Parallel')
                Switchvalue=2;
                app.VoltageSourceEditField.Visible = 'off';
                app.VoltageSourceEditFieldLabel.Visible = 'off';
                app.VLabel_2.Visible = 'off';
                app.CurrentSourceEditField.Visible = "on";
                app.CurrentSourceEditFieldLabel.Visible = "on";
                app.ALabel_2.Visible = 'on';
            end
        end

        % Button pushed function: HintButton
        function HintButtonPushed(app, event)
            msgbox("1) If the time and voltage source values are not entered while performing serial RLC circuit calculations, no graphic drawing will be made." + ...
                "2) If the time and current source values are not entered while performing parallel RLC circuit calculations, no graphic drawing will be made." + ...
                "3) It will not work if the R-L-C-I0-V0 value is not entered into the system." + ...
                "4) In our Tool, the resistance unit is ohm, the inductor unit is Henry, and the capacitor unit is Farad.");
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.ResistorREditField.Value='';
            app.InductorLEditField.Value='';
            app.CapacitorCEditField.Value='';
            app.InitialInductorCurrentEditField.Value='';
            app.InitialCapacitorVoltageEditField.Value='';
            app.CurrentSourceEditField.Value='';
            app.VoltageSourceEditField.Value='';
            app.TimeEditField.Value='';
            app.EditField.Value=0;
            app.EditField_2.Value=0;
            app.EditField_15.Value='';
            app.EditField_11.Value='';
            app.EditField_12.Value='';
            app.EditField_7.Value='';
            app.EditField_13.Value='';
            app.EditField_14.Value='';
            app.EditField_17.Value='';
            app.EditField_18.Value='';
            app.EditField_19.Value='';
            app.EditField_20.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RLCCircuitAnalysisToolLabel = uilabel(app.UIFigure);
            app.RLCCircuitAnalysisToolLabel.Text = 'RLC Circuit Analysis Tool';
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.InitialConditionsLabel = uilabel(app.UIFigure);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ω';
            app.HLabel = uilabel(app.UIFigure);
            app.HLabel.Text = 'H';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.NeperFrequencyLabel = uilabel(app.ResultsTab);
            app.NeperFrequencyLabel.Text = 'Neper Frequency';
            app.ResonantRadianFrequencyLabel = uilabel(app.ResultsTab);
            app.ResonantRadianFrequencyLabel.Text = 'Resonant Radian Frequency';
            app.DampedFrequencyLabel = uilabel(app.ResultsTab);
            app.DampedFrequencyLabel.Text = 'Damped Frequency';
            app.S1Label = uilabel(app.ResultsTab);
            app.S1Label.Text = 'S1';
            app.S2Label = uilabel(app.ResultsTab);
            app.S2Label.Text = 'S2';
            app.EditField = uieditfield(app.ResultsTab, 'numeric');
            app.EditField_2 = uieditfield(app.ResultsTab, 'numeric');
            app.EditField_7 = uieditfield(app.ResultsTab, 'text');
            app.ResponsetypeLabel = uilabel(app.ResultsTab);
            app.ResponsetypeLabel.Text = 'Response type';
            app.CoefficientLabel = uilabel(app.ResultsTab);
            app.CoefficientLabel.Text = 'Coefficient ';
            app.CoefficientLabel_2 = uilabel(app.ResultsTab);
            app.CoefficientLabel_2.Text = 'Coefficient';
            app.EditField_11 = uieditfield(app.ResultsTab, 'text');
            app.EditField_12 = uieditfield(app.ResultsTab, 'text');
            app.EditField_13 = uieditfield(app.ResultsTab, 'text');
            app.EditField_14 = uieditfield(app.ResultsTab, 'text');
            app.EditField_15 = uieditfield(app.ResultsTab, 'text');
            app.EditField_17 = uieditfield(app.ResultsTab, 'text');
            app.EditField_18 = uieditfield(app.ResultsTab, 'text');
            app.radsLabel = uilabel(app.ResultsTab);
            app.radsLabel.Text = 'rad/s';
            app.radsLabel_2 = uilabel(app.ResultsTab);
            app.radsLabel_2.Text = 'rad/s';
            app.radsLabel_3 = uilabel(app.ResultsTab);
            app.radsLabel_3.Text = 'rad/s';
            app.radsLabel_4 = uilabel(app.ResultsTab);
            app.radsLabel_4.Text = 'rad/s';
            app.radsLabel_5 = uilabel(app.ResultsTab);
            app.radsLabel_5.Text = 'rad/s';
            app.EditField_19 = uieditfield(app.ResultsTab, 'text');
            app.EditField_20 = uieditfield(app.ResultsTab, 'text');
            app.NaturalLabel = uilabel(app.ResultsTab);
            app.NaturalLabel.Text = 'Natural';
            app.StepLabel = uilabel(app.ResultsTab);
            app.StepLabel.Text = 'Step';
            app.SeriesNtrlGraphTab = uitab(app.TabGroup);
            app.SeriesNtrlGraphTab.Title = 'Series Ntrl Graph';
            app.UIAxes3 = uiaxes(app.SeriesNtrlGraphTab);
            title(app.UIAxes3, 'Series Natural Response Graph')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Current')
            zlabel(app.UIAxes3, 'Z')
            app.SeriesStepGraphTab = uitab(app.TabGroup);
            app.SeriesStepGraphTab.Title = 'Series Step Graph';
            app.UIAxes4 = uiaxes(app.SeriesStepGraphTab);
            title(app.UIAxes4, 'Series Step Response Graph')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Voltage')
            zlabel(app.UIAxes4, 'Z')
            app.ParallelNaturalGraphTab = uitab(app.TabGroup);
            app.ParallelNaturalGraphTab.Title = 'Parallel Natural Graph';
            app.UIAxes2 = uiaxes(app.ParallelNaturalGraphTab);
            title(app.UIAxes2, 'Parallel Natural Response Graph')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Voltage')
            zlabel(app.UIAxes2, 'Z')
            app.ParallelStepGraphTab = uitab(app.TabGroup);
            app.ParallelStepGraphTab.Title = 'Parallel Step Graph';
            app.UIAxes = uiaxes(app.ParallelStepGraphTab);
            title(app.UIAxes, 'Parallel Step Response Graph')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Current')
            zlabel(app.UIAxes, 'Z')
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Visible = 'off';
            app.ALabel_2.Text = 'A';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Series   ', 'Parallel'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Series   ';
            app.ResistorREditFieldLabel = uilabel(app.UIFigure);
            app.ResistorREditFieldLabel.Text = 'Resistor (R)';
            app.ResistorREditField = uieditfield(app.UIFigure, 'text');
            app.InductorLEditFieldLabel = uilabel(app.UIFigure);
            app.InductorLEditFieldLabel.Text = 'Inductor (L)';
            app.InductorLEditField = uieditfield(app.UIFigure, 'text');
            app.CapacitorCEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitorCEditFieldLabel.Text = 'Capacitor (C)';
            app.CapacitorCEditField = uieditfield(app.UIFigure, 'text');
            app.InitialInductorCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.InitialInductorCurrentEditFieldLabel.Text = 'Initial Inductor Current';
            app.InitialInductorCurrentEditField = uieditfield(app.UIFigure, 'text');
            app.InitialCapacitorVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.InitialCapacitorVoltageEditFieldLabel.Text = 'Initial Capacitor Voltage';
            app.InitialCapacitorVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentSourceEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentSourceEditFieldLabel.Visible = 'off';
            app.CurrentSourceEditFieldLabel.Text = 'Current Source';
            app.CurrentSourceEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentSourceEditField.Visible = 'off';
            app.VoltageSourceEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageSourceEditFieldLabel.Text = 'Voltage Source';
            app.VoltageSourceEditField = uieditfield(app.UIFigure, 'text');
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'series.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'parallel.png');
            app.HintButton = uibutton(app.UIFigure, 'push');
            app.HintButton.ButtonPushedFcn = createCallbackFcn(app, @HintButtonPushed, true);
            app.HintButton.Tooltip = {''};
            app.HintButton.Text = 'Hint';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SERIES_AND_PARALLEL_RLC_ANALYZER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end